classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalibrationTab                  matlab.ui.container.Tab
        DisplaysPanel                   matlab.ui.container.Panel
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes_iMat                     matlab.ui.control.UIAxes
        UIAxes_Cpupils                  matlab.ui.control.UIAxes
        UIAxes_Diagonal                 matlab.ui.control.UIAxes
        ColormapDropDown                matlab.ui.control.DropDown
        DisplayCamerainGUICheckBox      matlab.ui.control.CheckBox
        DisplayPhaseinGUICheckBox       matlab.ui.control.CheckBox
        Slider                          matlab.ui.control.Slider
        ConnectionpanelPanel            matlab.ui.container.Panel
        OpenCameraButton                matlab.ui.control.Button
        StopcameraButton                matlab.ui.control.Button
        SwitchLabel                     matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        devices                         matlab.ui.control.Label
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        ManualButton                    matlab.ui.control.Button
        AutoButton                      matlab.ui.control.Button
        CropLabel                       matlab.ui.control.Label
        DropDownCameras                 matlab.ui.control.DropDown
        BlackgroundButton               matlab.ui.control.Button
        CameracontrolPanel              matlab.ui.container.Panel
        ExposureEditFieldLabel          matlab.ui.control.Label
        ExposureEditField               matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
        Panel_SLMCalibration            matlab.ui.container.Panel
        CxEditFieldLabel                matlab.ui.control.Label
        CxEditField                     matlab.ui.control.NumericEditField
        CyEditFieldLabel                matlab.ui.control.Label
        CyEditField                     matlab.ui.control.NumericEditField
        DefaultvaluesButton             matlab.ui.control.Button
        RadiusEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        ModeDropDownLabel               matlab.ui.control.Label
        ModeZernDropDown                matlab.ui.control.DropDown
        LockCheckBox                    matlab.ui.control.CheckBox
        Panel_iMat                      matlab.ui.container.Panel
        CalibmodesButtonGroup           matlab.ui.container.ButtonGroup
        ZernikemodesButton              matlab.ui.control.RadioButton
        KLmodesButton                   matlab.ui.control.RadioButton
        ModulationPanel                 matlab.ui.container.Panel
        DigitalmodulationDropDownLabel  matlab.ui.control.Label
        DigitalmodulationDropDown       matlab.ui.control.DropDown
        NumberofstepsEditFieldLabel     matlab.ui.control.Label
        NumberofstepsEditField          matlab.ui.control.NumericEditField
        AmplitudelambdaDLabel           matlab.ui.control.Label
        AmplitudelambdaDEditField       matlab.ui.control.NumericEditField
        CreateinteractionmatrixButton   matlab.ui.control.Button
        SavedirButton                   matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        SaveCheckBox                    matlab.ui.control.CheckBox
        iMatParametersPanel             matlab.ui.container.Panel
        NumberofmodesEditFieldLabel     matlab.ui.control.Label
        NumberofmodesEditField          matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label       matlab.ui.control.Label
        AmplitudeEditField_2            matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Open_savepath                   matlab.ui.control.Button
        ExperimentTab                   matlab.ui.container.Tab
        LinearitytestPanel              matlab.ui.container.Panel
        UIAxes_linearity                matlab.ui.control.UIAxes
        RUNButton                       matlab.ui.control.Button
        ResetgraphButton                matlab.ui.control.Button
        AmplituderangeEditFieldLabel    matlab.ui.control.Label
        AmplituderangeEditField         matlab.ui.control.EditField
        StepsoflinearityEditFieldLabel  matlab.ui.control.Label
        StepsoflinearityEditField       matlab.ui.control.EditField
        ModeEditFieldLabel              matlab.ui.control.Label
        ModeEditField                   matlab.ui.control.EditField
        AdquisitionDataforexperimentsPanel  matlab.ui.container.Panel
        UIPrediciton                    matlab.ui.control.UIAxes
        CheckBox                        matlab.ui.control.CheckBox
        DisplayPredictionvaluesCheckBox  matlab.ui.control.CheckBox
        LoadphasemapsButton             matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        TypefileEditFieldLabel          matlab.ui.control.Label
        TypefileEditField               matlab.ui.control.EditField
        PupilSizepxEditFieldLabel       matlab.ui.control.Label
        PupilSizepxEditField            matlab.ui.control.EditField
        RUNButton_2                     matlab.ui.control.Button
        NumberofSamplesEditFieldLabel   matlab.ui.control.Label
        NumberofSamplesEditField        matlab.ui.control.EditField
        PauseCheckBox                   matlab.ui.control.CheckBox
        CorrectionCheckBox              matlab.ui.control.CheckBox
        SavedirButton_2                 matlab.ui.control.Button
        prgressLabel                    matlab.ui.control.Label
        Open_savepath_2                 matlab.ui.control.Button
        AllinfolderCheckBox             matlab.ui.control.CheckBox
        InteractionMatrixloaddataPanel  matlab.ui.container.Panel
        LoadiMatButton                  matlab.ui.control.Button
        ModulationPanel_Experiment_3    matlab.ui.container.Panel
        DigitalmodulationDropDown_4Label  matlab.ui.control.Label
        DigitalmodulationDropDown_2     matlab.ui.control.DropDown
        NumberofstepsEditField_5Label   matlab.ui.control.Label
        NumberofstepsEditField_2        matlab.ui.control.NumericEditField
        AmplitudelambdaDEditField_4Label  matlab.ui.control.Label
        AmplitudelambdaDEditField_2     matlab.ui.control.NumericEditField
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        iMat_info                       matlab.ui.control.EditField
        CloseLoopcommingsoonTab         matlab.ui.container.Tab
        LoadiMatPanel                   matlab.ui.container.Panel
        LoadiMatdirectoryButton_2       matlab.ui.control.Button
        LoadMatButton_2                 matlab.ui.control.Button
        ModulationPanel_Experiment_2    matlab.ui.container.Panel
        DigitalmodulationDropDown_3Label  matlab.ui.control.Label
        DigitalmodulationDropDown_3     matlab.ui.control.DropDown
        NumberofstepsEditField_4Label   matlab.ui.control.Label
        NumberofstepsEditField_4        matlab.ui.control.NumericEditField
        AmplitudelambdaDEditField_3Label  matlab.ui.control.Label
        AmplitudelambdaDEditField_3     matlab.ui.control.NumericEditField
        InteractionMatrixDropDown_2Label  matlab.ui.control.Label
        InteractionMatrixDropDown_2     matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        UIAxes_CCD                      matlab.ui.control.UIAxes
        UIAxes_Sethl                    matlab.ui.control.UIAxes
        DMcalibrationTab                matlab.ui.container.Tab
        ConnectDMPanel                  matlab.ui.container.Panel
        LampLabel                       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Switch_2                        matlab.ui.control.Switch
        CCDcameraviewButton             matlab.ui.control.Button
        StopCCDcameraButton             matlab.ui.control.Button
        CropmanualButton                matlab.ui.control.Button
        ZernikemodesDMcalibrationPanel  matlab.ui.container.Panel
        Ast45EditFieldLabel             matlab.ui.control.Label
        Ast45EditField                  matlab.ui.control.NumericEditField
        DefocusEditFieldLabel           matlab.ui.control.Label
        DefocusEditField                matlab.ui.control.NumericEditField
        Ast0EditFieldLabel              matlab.ui.control.Label
        Ast0EditField                   matlab.ui.control.NumericEditField
        TreYEditFieldLabel              matlab.ui.control.Label
        TreYEditField                   matlab.ui.control.NumericEditField
        ComXEditFieldLabel              matlab.ui.control.Label
        ComXEditField                   matlab.ui.control.NumericEditField
        ComYEditFieldLabel              matlab.ui.control.Label
        ComYEditField                   matlab.ui.control.NumericEditField
        TreXEditFieldLabel              matlab.ui.control.Label
        TreXEditField                   matlab.ui.control.NumericEditField
        TetYEditFieldLabel              matlab.ui.control.Label
        TetYEditField                   matlab.ui.control.NumericEditField
        SAstYEditFieldLabel             matlab.ui.control.Label
        SAstYEditField                  matlab.ui.control.NumericEditField
        SAb3EditFieldLabel              matlab.ui.control.Label
        SAb3EditField                   matlab.ui.control.NumericEditField
        SAstXEditFieldLabel             matlab.ui.control.Label
        SAstXEditField                  matlab.ui.control.NumericEditField
        TetXEditFieldLabel              matlab.ui.control.Label
        TetXEditField                   matlab.ui.control.NumericEditField
        StartModulationTestButton       matlab.ui.control.Button
        AmpEditFieldLabel               matlab.ui.control.Label
        AmpEditField                    matlab.ui.control.NumericEditField
        StopModulationTestButton        matlab.ui.control.Button
        LockcalbirationCheckBox         matlab.ui.control.CheckBox
        ResetTIPTILTButton              matlab.ui.control.Button
        ResetDMButton                   matlab.ui.control.Button
        PreviewDMcalibrationPanel       matlab.ui.container.Panel
        UIAxes8                         matlab.ui.control.UIAxes
        IncludeMasksTab                 matlab.ui.container.Tab
        SLMfourierplaneDigitalWFSPanel  matlab.ui.container.Panel
        WFS_filter                      matlab.ui.control.UIAxes
        LoadlButton_2                   matlab.ui.control.Button
        GenerateButton_2                matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        AddCheckBox_2                   matlab.ui.control.CheckBox
        SLMpupilplaneTelescopePanel     matlab.ui.container.Panel
        Tel_imag                        matlab.ui.control.UIAxes
        LoadlButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Image2_2                        matlab.ui.control.Image
        AddCheckBox                     matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        p1                              matlab.ui.control.Image
        p2                              matlab.ui.control.Image
        p3                              matlab.ui.control.Image
        p4                              matlab.ui.control.Image
        p5                              matlab.ui.control.Image
    end

properties (Access = public)
        cameraMem;
        nDir = 'C:\Users\optolab\Desktop\PULPOS\PULPOS_Devices_Control_Codes\SLM_Connection&Control_PULPOS\SDK_SLM\GUITest\negative_zernike';
        pDir = 'C:\Users\optolab\Desktop\PULPOS\PULPOS_Devices_Control_Codes\SLM_Connection&Control_PULPOS\SDK_SLM\GUITest\positive_zernike';
        saveDir = '..\SavedData_PULPOS_GitHub\saveData_iMats';
        exp_saveDir = '..\SavedData_PULPOS_GitHub\saveData';
        fft_filter_dir = '../';
        pupil_filter_dir = '../';
        Zernike_calibration_position = 1; % Description
        npx = 268;
        npx0 = 268;
        Cx0 = 924;
        Cy0 = 602;
        Cx = 924;
        Cy = 602;
        amp = 1;
        cam = [];
        SLMresolution_X = 1920;
        SLMresolution_Y = 1152;
        ZnOrKL = 1;
        ampFactor = 243;
        amp_imat = 0.1;
        number_modes = 16;
        Digi_modu = 0;
        LambdaOverD = 1; % Description
        Nstep = 20;
        im_corr = [];
        board_number = 1;
        wait_For_Trigger = 0;
        OutputPulseImageFlip = 1;
        OutputPulseImageRefresh = 0;
        height = [];
        width = [];
        depth = [];
        flip_immediate = 0;
        timeout_ms = 500;
        modalModul = [];
        r_i; % Corrdinates crop image in camera.
        cam_resol = [49,196,373,224];
        cam_resol2 = [49 + (373 -224)/2,196,224,224];
        v; %Camera propierty
        s; % Camera.source
        I_0;
        camNumber = 1;
        diffCam = 1;
        NewImatFile = [];
        ImatDir; % Description
        amp_linearity = 0;
        LinearitySteps = [];
        Mode4Linearity = [];
        Property48 % Description
        legLin = [];
        pyr2mode = [];
        iMat = []; % Description
        zernRec = []; % Description
        KLbasis = []; % Description
        DMangAmp = [0 0];% Description
        hdl;
        ZernDM = zeros(1,12); % Description
        zernikePattern;
        zernCode;
        res;
        state;
        TLDFM_Target;
        hysteresis;
        Zernike_ini; % Zernike value in the DM calibration
        DMexpo;% Description
        DM_modul; % Description
        Display_camera = 1; % Show camera in GUI
        Display_phase = 1; % Show phase in GUI
        Phase_file;
        X_phase;
        Y_z;
        Y_kl;
        Display_prediction = 0;
        Pause_Exp = 0;
        Correction_Exp = 0;
        slm;
        slm_pupil;
        tel_mask;
        add_tel_mask = 0;
        bitmap_WFS;
        lim_cam = 2^12-1;
        check_slm_pyr = 0;
        TT;
        exp_path_phase;
        all_files_exp;
        background;
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath .\Functions_GUI_PULPOS\
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
   value = app.Switch.Value;
   addpath Functions_GUI_PULPOS\
   addpath(genpath('.\'))
   app.LockCheckBox.Enable = 'on';


   if(eq(value(1:2),'On'))
       eval('SLM_setup');


       imagesc(app.UIAxes7,wrapToPi(corrSLM));
       app.im_corr = unwrap(unwrap(corrSLM,[],2),[],1);


       app.CxEditField.Value = app.Cx;
       app.CyEditField.Value = app.Cy;
       app.RadiusEditField.Value = app.npx0;
       
       
       app.devices.Text = ['Devices connected'];
       app.StatusLamp.Color = [0 1 0];
   elseif(value(1:3) == 'Off')
       app.devices.Text = ['Device Disconnected'];
       app.StatusLamp.Color = [1 0 0];
       app.check_slm_pyr = 0;
       app.slm_pupil = [];
   end
          
            
        end

        % Value changed function: LockCheckBox
        function LockCheckBoxValueChanged(app, event)
          value = app.LockCheckBox.Value;
            if value == 1
                app.CxEditField.Editable = 'off';
                app.CyEditField.Editable = 'off';
                app.DefaultvaluesButton.Enable = 'off';
                app.AmplitudeEditField.Editable = 'off';
                app.RadiusEditField.Editable = 'off';
                app.ModeZernDropDown.Enable = 'off';

            elseif value == 0
                app.CxEditField.Editable = 'on';
                app.CyEditField.Editable = 'on';
                app.DefaultvaluesButton.Enable = 'on';
                app.AmplitudeEditField.Editable = 'on';
                app.RadiusEditField.Editable = 'on';
                app.ModeZernDropDown.Enable = 'on';
            end
        end

        % Value changed function: DigitalmodulationDropDown
        function DigitalmodulationDropDownValueChanged(app, event)
                value = app.DigitalmodulationDropDown.Value
            if strcmp(value,'None')
                app.AmplitudelambdaDEditField.Editable = 'off';
                app.NumberofstepsEditField.Editable = 'off';
                app.NumberofstepsEditField.Value = 0;
                app.AmplitudelambdaDEditField.Value = 0;

                app.CreateinteractionmatrixButton.Enable = 'on';
                app.CreateinteractionmatrixButton.Text = 'Create interaction matrix';
                app.Label.Text = ' ';

                app.Digi_modu = 0;
            elseif strcmp(value,'Modulate using SLM pupil')
                app.AmplitudelambdaDEditField.Editable = 'on';
                app.NumberofstepsEditField.Editable = 'on';
                app.NumberofstepsEditField.Value = 20;
                app.AmplitudelambdaDEditField.Value = 1;

                app.CreateinteractionmatrixButton.Enable = 'on';
                app.CreateinteractionmatrixButton.Text = 'Create interaction matrix';
                app.Label.Text = ' ';

                app.Digi_modu = 1;
                app.LambdaOverD = 1;
                app.Nstep = 20;
            elseif strcmp(value,'Modulate DM')
                app.AmplitudelambdaDEditField.Editable = 'on';
                app.NumberofstepsEditField.Editable = 'on';
                app.CreateinteractionmatrixButton.Enable = 'off';
                app.CreateinteractionmatrixButton.Text = 'DM calibration first';
                app.Label.Text = 'Go to DM calibration Window and lock the calibration';
                app.NumberofstepsEditField.Value = 20;
                app.AmplitudelambdaDEditField.Value = 1;
                app.Digi_modu = 2;
                app.LambdaOverD = 1;
                app.Nstep = 20;            
            elseif strcmp(value,'Modulate using digital Pyramid')
                app.AmplitudelambdaDEditField.Editable = 'on';
                app.NumberofstepsEditField.Editable = 'on';
                app.NumberofstepsEditField.Value = 20;
                app.AmplitudelambdaDEditField.Value = 1;

                app.CreateinteractionmatrixButton.Enable = 'on';
                app.CreateinteractionmatrixButton.Text = 'Create interaction matrix';
                app.Label.Text = ' ';

                app.Digi_modu = 3;
                app.LambdaOverD = 1;
                app.Nstep = 20;
            end
        end

        % Value changed function: ModeZernDropDown
        function ModeZernDropDownValueChanged(app, event)
             value = app.ModeZernDropDown.Value;
            if strcmp(value,'Piston')
                app.Zernike_calibration_position = 1;
                app = Generate_calibration_phase(app);
            elseif strcmp(value,'Defocus')
                app.Zernike_calibration_position = 4;
                app = Generate_calibration_phase(app);
            end
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            value = app.RadiusEditField.Value;
            app.npx =  value;
            if value>app.Cx
                app.CxEditField.Value =value;
                app.Cx = value;
            end
            if value>app.Cy
                app.CyEditField.Value =value;
                app.Cy = value;
            end
            Generate_calibration_phase(app);
        end

        % Button pushed function: DefaultvaluesButton
        function DefaultvaluesButtonPushed(app, event)
            
            eval('initial_params');
            app.npx = app.npx0;
            app.Cx = app.Cx0;
            app.Cy = app.Cy0;
            app.amp = 1;
            app.RadiusEditField.Value = app.npx;
            app.CxEditField.Value = app.Cx;
            app.CyEditField.Value = app.Cy;
            app.AmplitudeEditField.Value = app.amp;

            app = Generate_calibration_phase(app);
        end

        % Button pushed function: OpenCameraButton
        function OpenCameraButtonPushed(app, event)
            addpath .\Functions_GUI_PULPOS\
            app.cam = 1;
            app.StopcameraButton.BackgroundColor = [1.00,0.32,0.32];
            app.OpenCameraButton.Enable = 'off';
            app.DropDownCameras.Enable = 'off';
            app.ManualButton.Enable = 'off';
            app.AutoButton.Enable = 'off';
            app.CreateinteractionmatrixButton.Enable = 'off';
            app = Check_GigeCam(app);
            v = app.v;
            v.FramesPerTrigger = inf;
            start(v);
            while app.cam == 1
                if v.FramesAvailable >0
                data = getdata(v,v.FramesAvailable);
                data = data(:,:,1,end);
                if ~isempty(app.r_i)
                  data = data(app.r_i(1):app.r_i(2),app.r_i(3):app.r_i(4));
                end
                imagesc(app.UIAxes_Cpupils,data,[min(data(:)) app.lim_cam]);
                app.Slider.Limits = [double(min(data(:)))+10 2^12-1];

                if ~isempty(app.r_i)
                app.UIAxes_Cpupils.Position = app.cam_resol2;
                else
                app.UIAxes_Cpupils.Position = app.cam_resol;
                app.UIAxes_Cpupils.XLim = [1 808];
                app.UIAxes_Cpupils.YLim = [1 616];
                end
                drawnow;
                end
            end
        end

        % Button pushed function: StopcameraButton
        function StopcameraButtonPushed(app, event)
             app.cam = 0;
             app.StopcameraButton.BackgroundColor = [0.96,0.96,0.96];
             app.OpenCameraButton.Enable = 'on';
             app.DropDownCameras.Enable = 'on';
             app.ManualButton.Enable = 'on';
             app.AutoButton.Enable = 'on';
             app.CreateinteractionmatrixButton.Enable = 'on';
             if ~isempty(app.v)
             stop(app.v)
             end
             fprintf("OK Reset")
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            app.s.Gain = value;
        end

        % Value changed function: CxEditField
        function CxEditFieldValueChanged(app, event)
            value = app.CxEditField.Value;
            if value<app.npx
                value = app.npx;
                app.CxEditField.Value =value;
            end
            app.Cx = value;
            Generate_calibration_phase(app);
        end

        % Value changed function: CyEditField
        function CyEditFieldValueChanged(app, event)
            value = app.CyEditField.Value;
            if value<app.npx
                value = app.npx;
                app.CyEditField.Value =value;
            end
            app.Cy = value;
            Generate_calibration_phase(app);
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            app.amp = value;
            Generate_calibration_phase(app);
        end

        % Button pushed function: SavedirButton
        function SavedirButtonPushed(app, event)
            dir_path = uigetdir(pwd,'Choose a path');
            drawnow;
            figure(app.UIFigure)
            app.saveDir = dir_path;
            app.EditField.Value = dir_path;
        end

        % Value changed function: SaveCheckBox
        function SaveCheckBoxValueChanged(app, event)
            value = app.SaveCheckBox.Value;
            if value == 0
                app.SavedirButton.Enable = 0;
                app.EditField.Value = ' ';
                app.saveDir = [];
            else
                app.SavedirButton.Enable = 1;
            end
        end

        % Button pushed function: CreateinteractionmatrixButton
        function CreateinteractionmatrixButtonPushed(app, event)
             app.cam = 0;
             app.StopcameraButton.BackgroundColor = [1.00,0.32,0.32];
             app.OpenCameraButton.Enable = 'on';
             app.DropDownCameras.Enable = 'on';
             app.ManualButton.Enable = 'on';
             app.AutoButton.Enable = 'on';
             app.CreateinteractionmatrixButton.Enable = 'on';
             if ~isempty(app.v)
             stop(app.v)
             end
             fprintf("OK Reset")
            iMat = [];
            
            [sp,sm,I_0] = Generate_imat(app);
%             sp2 = single(sp)./sum(single(sp),1);%-I_0(:)/sum(I_0(:));
%             sm2 = single(sm)./sum(single(sm),1);%-I_0(:)/sum(I_0(:));
%             iMat = 0.5*(sp2-sm2)/app.amp_imat;
%             Tfun =iMat'*iMat;
%             size(Tfun)
%             imagesc(app.UIAxes_iMat,Tfun);
%             app.UIAxes_iMat.XLim = [.5 size(Tfun,2)+.5];
%             app.UIAxes_iMat.YLim = [.5 size(Tfun,1)+.5];
%             app.UIAxes_iMat.ZLim = [min(Tfun(:)) max(Tfun(:))];
%             plot(app.UIAxes_Diagonal,diag(Tfun),'LineWidth',1.5);
%             app.UIAxes_Diagonal.XLim = [1 app.number_modes];
            
%             imagesc(app.UIAxes_iMat_2,Tfun);
%             app.UIAxes_iMat_2.XLim = [.5 size(Tfun,1)+.5];
%             app.UIAxes_iMat_2.YLim = [.5 size(Tfun,2)+.5];
            amp = app.amp_imat;
            coordinates = app.r_i;

            if app.ZnOrKL == 1
                name_mode =  'Zern';
            else
                name_mode = 'K-L';
            end
                
            if ~exist(app.saveDir, 'dir')
                mkdir(app.saveDir)
            end

            Zernike_ini = app.Zernike_ini;
            if app.camNumber == 1
            save([app.saveDir '/' date 'Test_Pyr_' name_mode '_mod' num2str(app.LambdaOverD) '_N' num2str(app.number_modes) '.mat'],"Zernike_ini",'I_0','sm','sp','amp','iMat','coordinates')
            else
            save([app.saveDir '/' date 'Test_Axicon_' name_mode '_mod' num2str(app.LambdaOverD) '_N' num2str(app.number_modes) '.mat'],"Zernike_ini",'I_0','sm','sp','amp','iMat','coordinates')
            end


            winopen(app.saveDir);
%             app.InteractionMatrixDropDown.Items = {'Interaction matrix in memory'};
%             app.InteractionMatrixDropDown_2.Items = {'Interaction matrix in memory'};
%             app.pyr2mode = Pseudoinv(app,I_0,sp,sm);
        end

        % Value changed function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanged(app, event)
            value = app.AmplitudeEditField_2.Value;
            app.amp_imat = value;
        end

        % Value changed function: NumberofmodesEditField
        function NumberofmodesEditFieldValueChanged(app, event)
            value = app.NumberofmodesEditField.Value;
            app.number_modes = value;
        end

        % Selection changed function: CalibmodesButtonGroup
        function CalibmodesButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalibmodesButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton,'Zernike modes')
                app.ZnOrKL = 1;
                app.UIAxes_Diagonal.XLabel.String = 'Zernike modes';
            elseif strcmp(selectedButton,'K-L modes')
                app.ZnOrKL = 2;
                app.UIAxes_Diagonal.XLabel.String = 'K-L modes';
            end
        end

        % Value changed function: AmplitudelambdaDEditField
        function AmplitudelambdaDEditFieldValueChanged(app, event)
            value = app.AmplitudelambdaDEditField.Value;
            app.LambdaOverD = value;
        end

        % Value changed function: NumberofstepsEditField
        function NumberofstepsEditFieldValueChanged(app, event)
            value = app.NumberofstepsEditField.Value;
            app.Nstep = value;
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            closepreview; 
            app = Check_GigeCam(app);
            c = app.UIAxes_Cpupils.Position;
            limc = c(4);
            c = [(c(1)+c(3))/2 (c(2)+c(4))/2];
            v = app.v;
            v.FramesPerTrigger = 1;
            start(v);
            wait(v,1)
            image =getdata(v,v.FramesAvailable);
            image= image(:,:,1,1);
            stop(v);
            tam_or = size(image,2);
            h = imagesc(app.UIAxes_Cpupils,image);
            app.UIAxes_Cpupils.Position = app.cam_resol;
            [py,px,cm] = size(image);
            app.UIAxes_Cpupils.XLim = [1 px];
            app.UIAxes_Cpupils.YLim = [1 py];
            pause(1/30)
            r = floor(getrect(app.UIAxes_Cpupils));
            r = [r(2),r(2)+r(4),r(1),r(1)+r(3)];
            if r(1) <=1 ;rxm = 1;else; rxm = r(1);end
            if r(3) <=1 ;rym = 1;else; rym = r(3);end
            if r(2) >= py; rxM = py; elseif r(2) == r(1); rxM = r(1)+1;else; rxM = r(2);end
            if r(4) >= px; ryM = px; elseif r(4) == r(3); ryM = r(3)+1;else; ryM = r(4); end
            r = [rxm,rxM,rym,ryM];
            app.r_i = r;
            image = image(rxm:rxM,rym:ryM);
            [py,px,cm] = size(image)
            app.UIAxes_Cpupils.XLim = [1 px];
            app.UIAxes_Cpupils.YLim = [1 py];
            imagesc(app.UIAxes_Cpupils,image);
            px = px/tam_or*limc
            py = py/tam_or*limc
            app.UIAxes_Cpupils.DataAspectRatio = [px px 1];
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app = Check_GigeCam(app);
            c = app.UIAxes_Cpupils.Position;
            c = [(c(1)+c(3))/2 (c(2)+c(4))/2];
            v = app.v;
            v.FramesPerTrigger =10;
            start(v)
            wait(v,1)
            image =getdata(v,v.FramesAvailable);
            image0 = image(:,:,1,1);
            stop(v)
            image = image0/max(image0(:));
            imagesc(app.UIAxes_Cpupils,image);
            app.UIAxes_Cpupils.Position = app.cam_resol;
            [py,px,cm] = size(image);
            app.UIAxes_Cpupils.XLim = [1 px];
            app.UIAxes_Cpupils.YLim = [1 py];
            pause(1/30)
            [x,y] = find(image>0.05);
            if min(x)-20 <=1 ;rxm = 1;else; rxm = min(x)-20 ; end
            if max(x)+20 >= py; rxM = py; else; rxM = max(x)+20; end
            if min(y)-20 <=1 ;rym = 1;else; rym = min(y)-20;end
            if max(y)+20 >= px; ryM = px; else; ryM = max(y)+20; end
            r = [rxm,rxM,rym,ryM];
            app.r_i = r;
            image = image0(r(1):r(2),r(3):r(4));
            [py,px,cm] = size(image);
            app.UIAxes_Cpupils.XLim = [1 px];
            app.UIAxes_Cpupils.YLim = [1 py];
            imagesc(app.UIAxes_Cpupils,image);
            app.UIAxes_Cpupils.Position = app.cam_resol2;
        end

        % Value changed function: DropDownCameras
        function DropDownCamerasValueChanged(app, event)
            value = app.DropDownCameras.Value;
            if strcmp(value,'Hamamatsu')
                d1 = app.camNumber;
                app.camNumber = 1;
                app.diffCam = abs(d1-app.camNumber);
            elseif strcmp(value,'Emergent')
                d1 = app.camNumber;
                app.camNumber = 2;
                app.diffCam = abs(d1-app.camNumber);    
            end

        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            if strcmp(value,'Parula')
                colormap(app.UIAxes7,"default");
                colormap(app.UIAxes_Cpupils,"default");
            elseif strcmp(value,'Gray')
                colormap(app.UIAxes7,"gray");
                colormap(app.UIAxes_Cpupils,"gray");
            elseif strcmp(value,'Hot')
                colormap(app.UIAxes7,"hot");
                colormap(app.UIAxes_Cpupils,"hot");
            elseif strcmp(value,'Jet')
                colormap(app.UIAxes7,"jet");
                colormap(app.UIAxes_Cpupils,"jet");
            elseif strcmp(value,'Bones')
                colormap(app.UIAxes7,"bone");
                colormap(app.UIAxes_Cpupils,"bone");
            elseif strcmp(value,'Autumn')
                colormap(app.UIAxes7,"autumn");
                colormap(app.UIAxes_Cpupils,"autumn");
            elseif strcmp(value,'Copper')
                colormap(app.UIAxes7,"copper");
                colormap(app.UIAxes_Cpupils,"copper");
            elseif strcmp(value,'HSV')
                colormap(app.UIAxes7,"hsv");
                colormap(app.UIAxes_Cpupils,"hsv");
            elseif strcmp(value,'Lines')
                colormap(app.UIAxes7,"lines");
                colormap(app.UIAxes_Cpupils,"lines");
            end        
        end

        % Value changed function: DisplayCamerainGUICheckBox
        function DisplayCamerainGUICheckBoxValueChanged(app, event)
            value = app.DisplayCamerainGUICheckBox.Value;
            app.Display_camera = value;
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            value = app.FrameRateEditField.Value;
            app.s.FrameRate = value;
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)
            value = app.ExposureEditField.Value;
            app.s.Exposure = value;
        end

        % Callback function
        function LoadiMatdirectoryButtonPushed(app, event)
            [fold] = uigetdir('.\');
            drawnow;
            figure(app.UIFigure)
            drawnow;
            figure(app.UIFigure)
            app.InteractionMatrixDropDown.Enable = 'on';
            app.LoadiMatButton.Enable = 'on';
            
            dir1 =dir(fold);
            app.ImatDir = fold;
            names = [];
            for n=3:size(dir1);names = [names;{dir1(n).name}];end
            app.InteractionMatrixDropDown.Items = names;
        end

        % Callback function
        function InteractionMatrixDropDownValueChanged(app, event)
            value = app.InteractionMatrixDropDown.Value;
            app.NewImatFile = value;
        end

        % Button pushed function: LoadiMatButton
        function LoadiMatButtonPushed(app, event)
            addpath Functions_GUI_PULPOS\
            [file,path]=uigetfile('*.mat*','Select configuration');
            drawnow;
            figure(app.UIFigure);
            app.NewImatFile = file;
            if file == 0
            app.iMat_info.Value = 'No file selected';
            else
            app.iMat_info.Value = file;
            drawnow;
            load([path file]);
%             app.r_i = coordinates;
            Tf = iMat'*iMat;
            app.pyr2mode = Pseudoinv(app,I_0,sp,sm);
            imagesc(app.UIAxes_iMat,Tf);
            app.UIAxes_iMat.XLim = [0.5 size(Tf,1)+0.5];
            app.UIAxes_iMat.YLim = [0.5 size(Tf,2)+0.5];
            stX = strfind(app.NewImatFile,'mod')
            if ~isempty(stX)
                modulation = str2num(app.NewImatFile(stX+3));
            else 
                modulation = 0;
            end
            if strfind(app.NewImatFile,'Pyr')
                app.camNumber = 1;
            elseif strfind(app.NewImatFile,'Axicon')
                app.camNumber = 2;
            end
            if strfind(app.NewImatFile,'Zern')
                app.ZnOrKL = 1;
            elseif strfind(app.NewImatFile,'K-L')
                app.ZnOrKL = 2;
            end
            display(['camera conected' num2str(app.camNumber)])
            app.LambdaOverD = modulation;
            app.r_i = coordinates;
            app.AmplitudelambdaDEditField_2.Value = modulation;
            app.UIAxes_Cpupils.XLim = [1 coordinates(2)-coordinates(1)];
            app.UIAxes_Cpupils.YLim = [1 coordinates(4)-coordinates(3)];
            app.I_0 = I_0;
            app.ReadyLamp.Color = [0,1,0];
            app.number_modes = size(iMat,2);
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: DigitalmodulationDropDown_2
        function DigitalmodulationDropDown_2ValueChanged(app, event)
            value = app.DigitalmodulationDropDown_2.Value;        
            if strcmp(value,'None')
                app.AmplitudelambdaDEditField_2.Editable = 'off';
                app.NumberofstepsEditField_2.Editable = 'off';
                app.NumberofstepsEditField_2.Value = 0;
                app.AmplitudelambdaDEditField_2.Value = 0;
                app.Digi_modu = 0;
            elseif strcmp(value,'Modulate using SLM')
                app.AmplitudelambdaDEditField_2.Editable = 'on';
                app.NumberofstepsEditField_2.Editable = 'on';
                app.NumberofstepsEditField_2.Value = 20;
                app.Digi_modu = 1;
                app.Nstep = 20;
            elseif strcmp(value,'Modulate DM')
                app.AmplitudelambdaDEditField_2.Editable = 'on';
                app.NumberofstepsEditField_2.Editable = 'on';
                app.RUNButton_2.Enable = 'off';
                app.RUNButton_2.Text = 'DM calibration first';
                app.Label.Text = 'Go to DM calibration Window and lock the calibration';
                app.NumberofstepsEditField.Value = 20;
                app.AmplitudelambdaDEditField.Value = 1;
                app.Digi_modu = 2;
                app.LambdaOverD = 1;
                app.Nstep = 20;
            end
        end

        % Callback function
        function ColormapDropDown_2ValueChanged(app, event)
            value = app.ColormapDropDown_2.Value;
            if strcmp(value,'Parula')
                colormap(app.UIAxes_iMat_2,"default");
            elseif strcmp(value,'Gray')
                colormap(app.UIAxes_iMat_2,"gray");
            elseif strcmp(value,'Hot')
                colormap(app.UIAxes_iMat_2,"hot");
            elseif strcmp(value,'Jet')
                colormap(app.UIAxes_iMat_2,"jet");
            elseif strcmp(value,'Bones')
                colormap(app.UIAxes_iMat_2,"bone");
            elseif strcmp(value,'Autumn')
                colormap(app.UIAxes_iMat_2,"autumn");
            elseif strcmp(value,'Copper')
                colormap(app.UIAxes_iMat_2,"copper");
            elseif strcmp(value,'HSV')
                colormap(app.UIAxes_iMat_2,"hsv");
            elseif strcmp(value,'Lines')
                colormap(app.UIAxes_iMat_2,"lines");
            end
        end

        % Value changed function: AmplitudelambdaDEditField_2
        function AmplitudelambdaDEditField_2ValueChanged(app, event)
            value = app.AmplitudelambdaDEditField_2.Value;
            app.LambdaOverD = value;

        end

        % Value changed function: NumberofstepsEditField_2
        function NumberofstepsEditField_2ValueChanged(app, event)
            value = app.NumberofstepsEditField_2.Value;
            app.Nstep = value;

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            X_raw = Linearity(app);
            Y = predictPyr(app,X_raw);
            X = linspace(-app.amp_linearity,app.amp_linearity,app.LinearitySteps);
            Yf = Y(str2num(app.Mode4Linearity),:);
            app.legLin = [app.legLin, {['Mode: ' num2str(app.Mode4Linearity)]}];
            plot(app.UIAxes_linearity,X,Yf,'LineWidth',2);
            L = legend(app.UIAxes_linearity,app.legLin);
            L.Position = [0.9137 0.8681 0.0838 0.0720];
            app.UIAxes_linearity.XLim = [-app.amp_linearity app.amp_linearity];
            app.UIAxes_linearity.YLim = [-app.amp_linearity app.amp_linearity];
            app.UIAxes_linearity.NextPlot = 'add';
        end

        % Value changed function: DigitalmodulationDropDown_3
        function DigitalmodulationDropDown_3ValueChanged(app, event)
            value = app.DigitalmodulationDropDown_3.Value;
            if strcmp(value,'None')
                app.AmplitudelambdaDEditField_3.Editable = 'off';
                app.NumberofstepsEditField_4.Editable = 'off';
                app.NumberofstepsEditField_4.Value = 0;
                app.AmplitudelambdaDEditField_3.Value = 0;
                app.Digi_modu = 0;
            elseif strcmp(value,'Modulate using SLM')
                app.AmplitudelambdaDEditField_3.Editable = 'on';
                app.NumberofstepsEditField_4.Editable = 'on';
                app.NumberofstepsEditField_4.Value = 20;
                app.AmplitudelambdaDEditField_3.Value = 1;
                app.Digi_modu = 1;
                app.LambdaOverD = 1;
                app.Nstep = 20;
            end

        end

        % Value changed function: StepsoflinearityEditField
        function StepsoflinearityEditFieldValueChanged(app, event)
            value = app.StepsoflinearityEditField.Value;
            app.LinearitySteps =  str2num(value);
        end

        % Value changed function: ModeEditField
        function ModeEditFieldValueChanged(app, event)
            value = app.ModeEditField.Value;
            app.Mode4Linearity = value;
            app.number_modes = value;
        end

        % Button pushed function: ResetgraphButton
        function ResetgraphButtonPushed(app, event)
            Y = [];
            app.legLin = [];
            app.UIAxes_linearity.NextPlot = 'replacechildren';
        end

        % Value changed function: AmplituderangeEditField
        function AmplituderangeEditFieldValueChanged(app, event)
            value = app.AmplituderangeEditField.Value;
            app.amp_linearity = str2num(value);
        end

        % Value changed function: AmplitudelambdaDEditField_3
        function AmplitudelambdaDEditField_3ValueChanged(app, event)
            value = app.AmplitudelambdaDEditField_3.Value;
            app.LambdaOverD = value;
        end

        % Value changed function: NumberofstepsEditField_4
        function NumberofstepsEditField_4ValueChanged(app, event)
            value = app.NumberofstepsEditField_4.Value;
            app.Nstep = value;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            value
            addpath DM_commands\
            addpath DM_lib\
            if(eq(value(1:2),'On'))
                app.Lamp.Color = [.4 .4 .4];
                [hfile,libname] = DM_library;
                [hdl,res,state,TLDFM_Target,hysteresis,ckeck_connecton] = DM_connect;
                if ckeck_connecton< 0
                    app.Lamp.Color = [1 1 0];
                    app.Switch_2.Value = 'Off';
                else

                app.hdl=hdl;
                app.res=res;
                app.state = state;
                app.TLDFM_Target=TLDFM_Target;
                app.hysteresis=hysteresis;
                [app.Zernike_ini,app.ZernDM,app.zernCode,app.zernikePattern,app.DMangAmp,app.DMexpo] = DM_zernike_state;
                app.Ast45EditField.Value = app.Zernike_ini(1);
                app.DefocusEditField.Value = app.Zernike_ini(2);
                DM_reload(hdl);
                app.Lamp.Color = [0 1 0];
                DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
                app.ResetTIPTILTButton.Enable =  'On';   
                app.ResetDMButton.Enable =  'On';   
                app.StartModulationTestButton.Enable =  'On';   
                app.StopModulationTestButton.Enable =  'On';   
                end
            else
                dhl = [];
                app.Lamp.Color = [1 0 0];
            end
        end

        % Value changed function: Ast45EditField
        function Ast45EditFieldValueChanged(app, event)
            value = app.Ast45EditField.Value;
            app.Zernike_ini(1) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: DefocusEditField
        function DefocusEditFieldValueChanged(app, event)
            value = app.DefocusEditField.Value;
            app.Zernike_ini(2) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: Ast0EditField
        function Ast0EditFieldValueChanged(app, event)
            value = app.Ast0EditField.Value;
            app.Zernike_ini(3) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: TreYEditField
        function TreYEditFieldValueChanged(app, event)
            value = app.TreYEditField.Value;
            app.Zernike_ini(4) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: ComXEditField
        function ComXEditFieldValueChanged(app, event)
            value = app.ComXEditField.Value;
            app.Zernike_ini(5) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: ComYEditField
        function ComYEditFieldValueChanged(app, event)
            value = app.ComYEditField.Value;
            app.Zernike_ini(6) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: TreXEditField
        function TreXEditFieldValueChanged(app, event)
            value = app.TreXEditField.Value;
            app.Zernike_ini(7) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: TetYEditField
        function TetYEditFieldValueChanged(app, event)
            value = app.TetYEditField.Value;
            app.Zernike_ini(8) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: SAstYEditField
        function SAstYEditFieldValueChanged(app, event)
            value = app.SAstYEditField.Value;
            app.Zernike_ini(9) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: SAb3EditField
        function SAb3EditFieldValueChanged(app, event)
            value = app.SAb3EditField.Value;
            app.Zernike_ini(10) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: SAstXEditField
        function SAstXEditFieldValueChanged(app, event)
            value = app.SAstXEditField.Value;
            app.Zernike_ini(11) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Value changed function: TetXEditField
        function TetXEditFieldValueChanged(app, event)
            value = app.TetXEditField.Value;
            app.Zernike_ini(12) = value;
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp)
        end

        % Button pushed function: StartModulationTestButton
        function StartModulationTestButtonPushed(app, event)
            app.DM_modul = 1;
            app.DMangAmp(2)=0;
            while app.DM_modul ==1
                DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,app.DMangAmp);
                app.DMangAmp(2) = app.DMangAmp(2)+1/180;
                if floor(app.DMangAmp(2)) == 1
                    app.DMangAmp(2) =-1;
                end
                pause(1/1000)
            end
        end

        % Button pushed function: StopModulationTestButton
        function StopModulationTestButtonPushed(app, event)
            app.DM_modul = 0;
        end

        % Value changed function: AmpEditField
        function AmpEditFieldValueChanged(app, event)
            value = app.AmpEditField.Value;
            app.DMangAmp(1) = value;
        end

        % Value changed function: LockcalbirationCheckBox
        function LockcalbirationCheckBoxValueChanged(app, event)
            value = app.LockcalbirationCheckBox.Value;
            if value == 1
            app.Ast45EditField.Editable = 'off';
            app.Ast0EditField.Editable = 'off';
            app.DefocusEditField.Editable = 'off';
            app.TetXEditField.Editable = 'off';
            app.SAstXEditField.Editable = 'off';
            app.SAstYEditField.Editable = 'off';
            app.TreYEditField.Editable = 'off';
            app.TreXEditField.Editable = 'off';
            app.ComXEditField.Editable = 'off';
            app.ComYEditField.Editable = 'off';
            app.SAb3EditField.Editable = 'off';
            app.SAb3EditField.Editable = 'off';
            app.CreateinteractionmatrixButton.Enable = 'on';
            app.CreateinteractionmatrixButton.Text = 'Create interaction matrix';
            app.RUNButton.Enable = 'on';
            app.RUNButton.Text = 'RUN';
            app.RUNButton_2.Enable = 'on';
            app.RUNButton_2.Text = 'RUN';
            app.Label.Text = ' ';
            elseif value == 0

            app.Ast45EditField.Editable = 'on';
            app.Ast0EditField.Editable = 'on';
            app.DefocusEditField.Editable = 'on';
            app.TetXEditField.Editable = 'on';
            app.SAstXEditField.Editable = 'on';
            app.SAstYEditField.Editable = 'on';
            app.TreYEditField.Editable = 'on';
            app.TreXEditField.Editable = 'on';
            app.ComXEditField.Editable = 'on';
            app.ComYEditField.Editable = 'on';
            app.SAb3EditField.Editable = 'on';
            app.SAb3EditField.Editable = 'on';
            if app.Digi_modu ==2;
            app.CreateinteractionmatrixButton.Enable = 'off';
            app.CreateinteractionmatrixButton.Text = 'Go to DM calibration Window and lock the calibration';
            app.Label.Text = 'Lock the calibration';
            end
            
            end
        end

        % Button pushed function: CCDcameraviewButton
        function CCDcameraviewButtonPushed(app, event)
            app = Check_GigeCam(app);
            preview(app.v)
        end

        % Button pushed function: ResetDMButton
        function ResetDMButtonPushed(app, event)
            DM_reload(app.hdl);
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,[0 0])
        end

        % Button pushed function: ResetTIPTILTButton
        function ResetTIPTILTButtonPushed(app, event)
            DM_write(app.hdl,app.zernCode,app.Zernike_ini,app.zernikePattern,[0 0])
        end

        % Value changed function: DisplayPhaseinGUICheckBox
        function DisplayPhaseinGUICheckBoxValueChanged(app, event)
            value = app.DisplayPhaseinGUICheckBox.Value;
            app.Display_phase = value;
        end

        % Callback function: CalibrationTab
        function CalibrationTabButtonDown(app, event)
            app.p1.Enable = 'On';
            app.p2.Enable = 'Off';
            app.p3.Enable = 'Off';
            app.p4.Enable = 'Off';
            app.p5.Enable = 'Off';
        end

        % Callback function: ExperimentTab
        function ExperimentTabButtonDown(app, event)
            app.p1.Enable = 'Off';
            app.p2.Enable = 'On';
            app.p3.Enable = 'Off';
            app.p4.Enable = 'Off';
            app.p5.Enable = 'Off';
        end

        % Callback function: CloseLoopcommingsoonTab
        function CloseLoopcommingsoonTabButtonDown(app, event)
            app.p1.Enable = 'Off';
            app.p2.Enable = 'Off';
            app.p3.Enable = 'On';
            app.p4.Enable = 'Off';
            app.p5.Enable = 'Off';
        end

        % Callback function: DMcalibrationTab
        function DMcalibrationTabButtonDown(app, event)
            app.p1.Enable = 'Off';
            app.p2.Enable = 'Off';
            app.p3.Enable = 'Off';
            app.p4.Enable = 'On';
            app.p5.Enable = 'Off';
        end

        % Button pushed function: LoadphasemapsButton
        function LoadphasemapsButtonPushed(app, event)
            addpath Functions_GUI_PULPOS\
            app.Y_z = 0;
            app.Y_kl = 0;
            [file,path]=uigetfile('*.mat*','Select configuration');
            drawnow;
            app.exp_path_phase = path;
            figure(app.UIFigure);
            app.Phase_file = file;
            if file == 0
            app.EditField_2.Value = 'No file selected';
            else
            app.EditField_2.Value = file;
            drawnow;
            load([path file]);
            app.X_phase = X_phase;
            app.Y_z = Y_z;
            app.Y_kl = Y_kl;

            if sum(size(X_phase)== app.SLMresolution_X)
                app.TypefileEditField.Value = 'Bitmap';
                app.PupilSizepxEditField.Value = 'Not specified';
                app.NumberofSamplesEditField.Value = num2str(size(X_phase,3));
            elseif size(X_phase,1)==size(X_phase,2)
                app.TypefileEditField.Value = 'Pupil phasemap';
                app.PupilSizepxEditField.Value = num2str(size(X_phase,1));
                app.NumberofSamplesEditField.Value = num2str(size(X_phase,3));
            end
            app.RUNButton_2.Enable = 1;
            end

        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            addpath Functions_GUI_PULPOS\
            X_s = [];
            Y_p = [0 0];
            Y_0 = [0 0];


            if app.all_files_exp == 1
                files = dir(app.exp_path_phase)
                for fdx = 3:length(files)

                    app.cam = 0;
                    if ~isempty(app.v)
                        stop(app.v)
                    end

                    nameFile = files(fdx).name;
                    load([app.exp_path_phase '/' nameFile]);
                    app.X_phase = -X_phase;
                    app.Y_z = -Y_z;
                    app.Y_kl = Y_kl;

                    [X_s,Y_p,Y_0] = Generate_experiment_data(app);
                    Y_z = app.Y_z;
                    
                    if app.ZnOrKL == 1
                        name_mode =  'Zern';
                    else
                        name_mode = 'K-L';
                    end
                    app.cam = 0;
                    if ~isempty(app.v)
                        stop(app.v)
                    end
                    if ~exist(app.exp_saveDir, 'dir')
                        mkdir(app.exp_saveDir)
                    end
                    if app.camNumber == 1
                        save([app.exp_saveDir '/' nameFile(1:end-4) '_Test_Pyr_' name_mode '_mod' num2str(app.LambdaOverD) '.mat'],'X_s')
                    else
                        save([app.exp_saveDir '/' nameFile(1:end-4) 'Test_Axicon_' name_mode '_mod' num2str(app.LambdaOverD) '.mat'],'X_s')
                    end
                end
        
            else

            [X_s,Y_p,Y_0] = Generate_experiment_data(app);
            
            Y_z = app.Y_z;

            if app.ZnOrKL == 1
                name_mode =  'Zern';
            else
                name_mode = 'K-L';
            end
            
            app.cam = 0;
            if ~isempty(app.v)
                stop(app.v)
            end


            if ~exist(app.exp_saveDir, 'dir')
                mkdir(app.exp_saveDir)
            end
            if app.camNumber == 1
            save([app.exp_saveDir '/' app.Phase_file(1:end-4) '_Test_Pyr_' name_mode '_mod' num2str(app.LambdaOverD) '.mat'],'X_s')
            else
            save([app.exp_saveDir '/' app.Phase_file(1:end-4) 'Test_Axicon_' name_mode '_mod' num2str(app.LambdaOverD) '.mat'],'X_s')
            end

            end

            winopen(app.exp_saveDir);
        end

        % Value changed function: DisplayPredictionvaluesCheckBox
        function DisplayPredictionvaluesCheckBoxValueChanged(app, event)
            value = app.DisplayPredictionvaluesCheckBox.Value;
            app.Display_prediction = value;
        end

        % Value changed function: PauseCheckBox
        function PauseCheckBoxValueChanged(app, event)
            value = app.PauseCheckBox.Value;
            app.Pause_Exp = value;
        end

        % Value changed function: CorrectionCheckBox
        function CorrectionCheckBoxValueChanged(app, event)
            value = app.CorrectionCheckBox.Value;
            app.Correction_Exp = value;
        end

        % Callback function
        function STARTButtonValueChanged(app, event)
            
        end

        % Button pushed function: Open_savepath
        function Open_savepathButtonPushed(app, event)
            winopen(app.saveDir);
        end

        % Size changed function: DMcalibrationTab
        function DMcalibrationTabSizeChanged(app, event)
            position = app.DMcalibrationTab.Position;
            
        end

        % Size changed function: IncludeMasksTab
        function IncludeMasksTabSizeChanged(app, event)
            position = app.IncludeMasksTab.Position;
            
        end

        % Callback function: IncludeMasksTab
        function IncludeMasksTabButtonDown(app, event)
            app.p1.Enable = 'Off';
            app.p2.Enable = 'Off';
            app.p3.Enable = 'Off';
            app.p4.Enable = 'Off';
            app.p5.Enable = 'On';
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            clear app.slm
            app.check_slm_pyr = 0;
            app = Add_WFS_filter(app);
            imagesc(app.WFS_filter,app.bitmap_WFS);
            app.WFS_filter.XLim = [1 size(app.bitmap_WFS,1)];
            app.WFS_filter.YLim = [1 size(app.bitmap_WFS,1)];
            drawnow;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        if exist('app.zernRec')
        app.tel_mask = reshape(app.zernRec(1,:),[app.npx*2 app.npx*2])~=0; 
        imagesc(app.Tel_imag,app.tel_mask);
        app.Tel_imag.XLim = [1 size(app.tel_mask,1)];
        app.Tel_imag.YLim = [1 size(app.tel_mask,1)];
        drawnow;    
        elseif exist('app.KLbasis')
        app.tel_mask = reshape(app.KLbasis(1,:),[app.npx*2 app.npx*2])~=0;
        imagesc(app.Tel_imag,app.tel_mask);
        app.Tel_imag.XLim = [1 size(app.tel_mask,1)];
        app.Tel_imag.YLim = [1 size(app.tel_mask,1)];
        drawnow;
        end
        end

        % Value changed function: AddCheckBox
        function AddCheckBoxValueChanged(app, event)
            value = app.AddCheckBox.Value;
            if value == 1
                app.ResetButton.Enable = 'On';
                app.LoadlButton.Enable = 'On';
                app.add_tel_mask = 1;
            else
                app.ResetButton.Enable = 'Off';
                app.LoadlButton.Enable = 'Off';
                app.add_tel_mask = 0;
            end
        end

        % Value changed function: AddCheckBox_2
        function AddCheckBox_2ValueChanged(app, event)
            value = app.AddCheckBox_2.Value;
            if value == 1
                app.GenerateButton_2.Enable = 'On';
                app.LoadlButton_2.Enable = 'On';
            else
                app.GenerateButton_2.Enable = 'Off';
                app.LoadlButton_2.Enable = 'Off';
            end
        end

        % Button pushed function: LoadlButton
        function LoadlButtonPushed(app, event)
            
            [file,path]=uigetfile('*.mat*','Select pupil mask of resoltuion 268x268',app.pupil_filter_dir);
            if file ~=0
            app.pupil_filter_dir = path;
            uiopen([path file],tf);
            drawnow;
            figure(app.UIFigure)
            if exist('mask')
                app.add_tel_mask = ask;
            elseif exist('pupil')
                app.add_tel_mask = pupil;
            elseif exist('pupil_mask')
                app.add_tel_mask = pupil_mask;
            elseif exist('pup')
                app.add_tel_mask = pup;
            end
            imagesc(app.Tel_imag,app.add_tel_mask);
            app.Tel_imag.XLim = [1 size(app.add_tel_mask,1)];
            app.Tel_imag.YLim = [1 size(app.add_tel_mask,1)];
            drawnow;
            end
        end

        % Button pushed function: LoadlButton_2
        function LoadlButton_2Pushed(app, event)
            [file,path]=uigetfile('*.mat*','Select bitmap file filter',app.fft_filter_dir);
            if file ~=0
            app.fft_filter_dir = path;
            drawnow;
            figure(app.UIFigure);
            load([path file]);
            var = who;
            eval(['bitmap=' var{1} ';']);
            app.bitmap_WFS = bitmap;
            app = Add_WFS_filter(app);
            imagesc(app.WFS_filter,bitmap);
            app.WFS_filter.XLim = [1 size(bitmap,1)];
            app.WFS_filter.YLim = [1 size(bitmap,1)];
            drawnow;
            end
        end

        % Callback function: WFS_filter
        function WFS_filterButtonDown(app, event)
            
        end

        % Callback function: Tel_imag
        function Tel_imagButtonDown(app, event)
            
        end

        % Button pushed function: SavedirButton_2
        function SavedirButton_2Pushed(app, event)
            dir_path = uigetdir(pwd,'Choose a path');
            drawnow;
            figure(app.UIFigure)
            app.exp_saveDir = dir_path;
            app.EditField.Value = dir_path;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.UIAxes_Cpupils.CLim = [0 value];
            app.lim_cam = value;
        end

        % Callback function: UIAxes_Cpupils
        function UIAxes_CpupilsButtonDown(app, event)
            
        end

        % Size changed function: CalibrationTab
        function CalibrationTabSizeChanged(app, event)
            position = app.CalibrationTab.Position;
            
        end

        % Size changed function: ExperimentTab
        function ExperimentTabSizeChanged(app, event)
            position = app.ExperimentTab.Position;
            
        end

        % Size changed function: CloseLoopcommingsoonTab
        function CloseLoopcommingsoonTabSizeChanged(app, event)
            position = app.CloseLoopcommingsoonTab.Position;
            
        end

        % Button pushed function: Open_savepath_2
        function Open_savepath_2ButtonPushed(app, event)
            winopen(app.exp_saveDir);
        end

        % Value changed function: AllinfolderCheckBox
        function AllinfolderCheckBoxValueChanged(app, event)
            value = app.AllinfolderCheckBox.Value;
            app.all_files_exp = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.SizeChangedFcn = createCallbackFcn(app, @CalibrationTabSizeChanged, true);
            app.CalibrationTab.Title = 'Calibration';
            app.DisplaysPanel = uipanel(app.CalibrationTab);
            app.DisplaysPanel.Title = 'Displays';
            app.UIAxes7 = uiaxes(app.DisplaysPanel);
            title(app.UIAxes7, 'SLM Bitmap')
            app.UIAxes7.XLim = [1 1280];
            app.UIAxes7.YLim = [0 1024];
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTickLabel = '';
            app.UIAxes_iMat = uiaxes(app.DisplaysPanel);
            title(app.UIAxes_iMat, 'iMat')
            app.UIAxes_iMat.XLim = [0 1920];
            app.UIAxes_iMat.YLim = [0 1152];
            app.UIAxes_iMat.XTick = [];
            app.UIAxes_iMat.YTickLabel = '';
            app.UIAxes_Cpupils = uiaxes(app.DisplaysPanel);
            title(app.UIAxes_Cpupils, 'Pyr Camera')
            app.UIAxes_Cpupils.XTick = [];
            app.UIAxes_Cpupils.YTickLabel = '';
            app.UIAxes_Diagonal = uiaxes(app.DisplaysPanel);
            title(app.UIAxes_Diagonal, 'Diagonals')
            xlabel(app.UIAxes_Diagonal, 'Zernike modes')
            ylabel(app.UIAxes_Diagonal, 'Weight')
            app.UIAxes_Diagonal.YTickLabel = '';
            app.ColormapDropDown = uidropdown(app.DisplaysPanel);
            app.ColormapDropDown.Items = {'Parula', 'Gray', 'Hot', 'Jet', 'Bones', 'Copper'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Parula';
            app.DisplayCamerainGUICheckBox = uicheckbox(app.DisplaysPanel);
            app.DisplayCamerainGUICheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayCamerainGUICheckBoxValueChanged, true);
            app.DisplayCamerainGUICheckBox.Text = 'Display Camera in GUI';
            app.DisplayCamerainGUICheckBox.Value = true;
            app.DisplayPhaseinGUICheckBox = uicheckbox(app.DisplaysPanel);
            app.DisplayPhaseinGUICheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayPhaseinGUICheckBoxValueChanged, true);
            app.DisplayPhaseinGUICheckBox.Text = 'Display Phase in GUI';
            app.DisplayPhaseinGUICheckBox.Value = true;
            app.Slider = uislider(app.DisplaysPanel);
            app.Slider.Limits = [1 65535];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 65535;
            app.ConnectionpanelPanel = uipanel(app.CalibrationTab);
            app.ConnectionpanelPanel.Title = 'Connection panel';
            app.OpenCameraButton = uibutton(app.ConnectionpanelPanel, 'push');
            app.OpenCameraButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCameraButtonPushed, true);
            app.OpenCameraButton.Text = 'Open Camera';
            app.StopcameraButton = uibutton(app.ConnectionpanelPanel, 'push');
            app.StopcameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopcameraButtonPushed, true);
            app.StopcameraButton.Text = 'Stop camera';
            app.SwitchLabel = uilabel(app.ConnectionpanelPanel);
            app.SwitchLabel.Text = '';
            app.Switch = uiswitch(app.ConnectionpanelPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.devices = uilabel(app.ConnectionpanelPanel);
            app.devices.Text = '';
            app.StatusLampLabel = uilabel(app.ConnectionpanelPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ConnectionpanelPanel);
            app.ManualButton = uibutton(app.ConnectionpanelPanel, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.AutoButton = uibutton(app.ConnectionpanelPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.CropLabel = uilabel(app.ConnectionpanelPanel);
            app.CropLabel.Text = 'Crop';
            app.DropDownCameras = uidropdown(app.ConnectionpanelPanel);
            app.DropDownCameras.Items = {'Hamamatsu', 'Emergent'};
            app.DropDownCameras.ValueChangedFcn = createCallbackFcn(app, @DropDownCamerasValueChanged, true);
            app.DropDownCameras.Value = 'Hamamatsu';
            app.BlackgroundButton = uibutton(app.ConnectionpanelPanel, 'push');
            app.BlackgroundButton.Text = 'Blackground';
            app.CameracontrolPanel = uipanel(app.CalibrationTab);
            app.CameracontrolPanel.Title = 'Camera control';
            app.ExposureEditFieldLabel = uilabel(app.CameracontrolPanel);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.CameracontrolPanel, 'numeric');
            app.ExposureEditField.Limits = [0 Inf];
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.ExposureEditField.Value = 30000;
            app.GainEditFieldLabel = uilabel(app.CameracontrolPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.CameracontrolPanel, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 256;
            app.FrameRateEditFieldLabel = uilabel(app.CameracontrolPanel);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.CameracontrolPanel, 'numeric');
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.FrameRateEditField.Value = 30;
            app.Panel_SLMCalibration = uipanel(app.CalibrationTab);
            app.Panel_SLMCalibration.Title = 'SLM Calibration';
            app.CxEditFieldLabel = uilabel(app.Panel_SLMCalibration);
            app.CxEditFieldLabel.Text = 'Cx';
            app.CxEditField = uieditfield(app.Panel_SLMCalibration, 'numeric');
            app.CxEditField.ValueChangedFcn = createCallbackFcn(app, @CxEditFieldValueChanged, true);
            app.CxEditField.Editable = 'off';
            app.CxEditField.Value = 924;
            app.CyEditFieldLabel = uilabel(app.Panel_SLMCalibration);
            app.CyEditFieldLabel.Text = 'Cy';
            app.CyEditField = uieditfield(app.Panel_SLMCalibration, 'numeric');
            app.CyEditField.ValueChangedFcn = createCallbackFcn(app, @CyEditFieldValueChanged, true);
            app.CyEditField.Editable = 'off';
            app.CyEditField.Value = 602;
            app.DefaultvaluesButton = uibutton(app.Panel_SLMCalibration, 'push');
            app.DefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultvaluesButtonPushed, true);
            app.DefaultvaluesButton.Enable = 'off';
            app.DefaultvaluesButton.Text = 'Default values';
            app.RadiusEditFieldLabel = uilabel(app.Panel_SLMCalibration);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.Panel_SLMCalibration, 'numeric');
            app.RadiusEditField.Limits = [1 1152];
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.Editable = 'off';
            app.RadiusEditField.Value = 268;
            app.AmplitudeEditFieldLabel = uilabel(app.Panel_SLMCalibration);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.Panel_SLMCalibration, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Editable = 'off';
            app.AmplitudeEditField.Value = 1;
            app.ModeDropDownLabel = uilabel(app.Panel_SLMCalibration);
            app.ModeDropDownLabel.Enable = 'off';
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeZernDropDown = uidropdown(app.Panel_SLMCalibration);
            app.ModeZernDropDown.Items = {'Piston', 'Defocus'};
            app.ModeZernDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeZernDropDownValueChanged, true);
            app.ModeZernDropDown.Enable = 'off';
            app.ModeZernDropDown.Value = 'Piston';
            app.LockCheckBox = uicheckbox(app.Panel_SLMCalibration);
            app.LockCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCheckBoxValueChanged, true);
            app.LockCheckBox.Enable = 'off';
            app.LockCheckBox.Text = 'Lock';
            app.LockCheckBox.Value = true;
            app.Panel_iMat = uipanel(app.CalibrationTab);
            app.Panel_iMat.Title = 'iMat';
            app.CalibmodesButtonGroup = uibuttongroup(app.Panel_iMat);
            app.CalibmodesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalibmodesButtonGroupSelectionChanged, true);
            app.CalibmodesButtonGroup.Title = 'Calib modes';
            app.ZernikemodesButton = uiradiobutton(app.CalibmodesButtonGroup);
            app.ZernikemodesButton.Text = 'Zernike modes';
            app.ZernikemodesButton.Value = true;
            app.KLmodesButton = uiradiobutton(app.CalibmodesButtonGroup);
            app.KLmodesButton.Text = 'K-L modes';
            app.ModulationPanel = uipanel(app.Panel_iMat);
            app.ModulationPanel.Title = 'Modulation';
            app.DigitalmodulationDropDownLabel = uilabel(app.ModulationPanel);
            app.DigitalmodulationDropDownLabel.Text = 'Digital modulation';
            app.DigitalmodulationDropDown = uidropdown(app.ModulationPanel);
            app.DigitalmodulationDropDown.Items = {'None', 'Modulate using SLM pupil', 'Modulate DM', 'Modulate using digital Pyramid'};
            app.DigitalmodulationDropDown.ValueChangedFcn = createCallbackFcn(app, @DigitalmodulationDropDownValueChanged, true);
            app.DigitalmodulationDropDown.Value = 'None';
            app.NumberofstepsEditFieldLabel = uilabel(app.ModulationPanel);
            app.NumberofstepsEditFieldLabel.Text = 'Number of steps';
            app.NumberofstepsEditField = uieditfield(app.ModulationPanel, 'numeric');
            app.NumberofstepsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofstepsEditFieldValueChanged, true);
            app.NumberofstepsEditField.Editable = 'off';
            app.AmplitudelambdaDLabel = uilabel(app.ModulationPanel);
            app.AmplitudelambdaDLabel.Text = 'Amplitude $\lambda / D$';
            app.AmplitudelambdaDEditField = uieditfield(app.ModulationPanel, 'numeric');
            app.AmplitudelambdaDEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudelambdaDEditFieldValueChanged, true);
            app.AmplitudelambdaDEditField.Editable = 'off';
            app.CreateinteractionmatrixButton = uibutton(app.Panel_iMat, 'push');
            app.CreateinteractionmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CreateinteractionmatrixButtonPushed, true);
            app.CreateinteractionmatrixButton.Text = 'Create interaction matrix';
            app.SavedirButton = uibutton(app.Panel_iMat, 'push');
            app.SavedirButton.ButtonPushedFcn = createCallbackFcn(app, @SavedirButtonPushed, true);
            app.SavedirButton.Enable = 'off';
            app.SavedirButton.Text = {'Save'; 'dir'};
            app.EditField = uieditfield(app.Panel_iMat, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.EditField.Value = ' ';
            app.SaveCheckBox = uicheckbox(app.Panel_iMat);
            app.SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBoxValueChanged, true);
            app.SaveCheckBox.Text = 'Save';
            app.iMatParametersPanel = uipanel(app.Panel_iMat);
            app.iMatParametersPanel.Title = 'iMat Parameters';
            app.NumberofmodesEditFieldLabel = uilabel(app.iMatParametersPanel);
            app.NumberofmodesEditFieldLabel.Text = 'Number of modes';
            app.NumberofmodesEditField = uieditfield(app.iMatParametersPanel, 'numeric');
            app.NumberofmodesEditField.Limits = [1 1000];
            app.NumberofmodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmodesEditFieldValueChanged, true);
            app.NumberofmodesEditField.Value = 16;
            app.AmplitudeEditField_2Label = uilabel(app.iMatParametersPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.iMatParametersPanel, 'numeric');
            app.AmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanged, true);
            app.AmplitudeEditField_2.Value = 0.1;
            app.Label = uilabel(app.iMatParametersPanel);
            app.Label.Text = '';
            app.Open_savepath = uibutton(app.Panel_iMat, 'push');
            app.Open_savepath.ButtonPushedFcn = createCallbackFcn(app, @Open_savepathButtonPushed, true);
            app.Open_savepath.Text = {'Open'; 'folder'};
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.SizeChangedFcn = createCallbackFcn(app, @ExperimentTabSizeChanged, true);
            app.ExperimentTab.Title = 'Experiment';
            app.LinearitytestPanel = uipanel(app.ExperimentTab);
            app.LinearitytestPanel.Title = 'Linearity test';
            app.UIAxes_linearity = uiaxes(app.LinearitytestPanel);
            title(app.UIAxes_linearity, 'Linearity')
            xlabel(app.UIAxes_linearity, 'Input value')
            ylabel(app.UIAxes_linearity, 'Response')
            zlabel(app.UIAxes_linearity, 'Z')
            app.RUNButton = uibutton(app.LinearitytestPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ResetgraphButton = uibutton(app.LinearitytestPanel, 'push');
            app.ResetgraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetgraphButtonPushed, true);
            app.ResetgraphButton.Text = 'Reset  graph';
            app.AmplituderangeEditFieldLabel = uilabel(app.LinearitytestPanel);
            app.AmplituderangeEditFieldLabel.Text = 'Amplitude range';
            app.AmplituderangeEditField = uieditfield(app.LinearitytestPanel, 'text');
            app.AmplituderangeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplituderangeEditFieldValueChanged, true);
            app.StepsoflinearityEditFieldLabel = uilabel(app.LinearitytestPanel);
            app.StepsoflinearityEditFieldLabel.Text = 'Steps of linearity';
            app.StepsoflinearityEditField = uieditfield(app.LinearitytestPanel, 'text');
            app.StepsoflinearityEditField.ValueChangedFcn = createCallbackFcn(app, @StepsoflinearityEditFieldValueChanged, true);
            app.ModeEditFieldLabel = uilabel(app.LinearitytestPanel);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.ModeEditField = uieditfield(app.LinearitytestPanel, 'text');
            app.ModeEditField.ValueChangedFcn = createCallbackFcn(app, @ModeEditFieldValueChanged, true);
            app.AdquisitionDataforexperimentsPanel = uipanel(app.ExperimentTab);
            app.AdquisitionDataforexperimentsPanel.Title = 'Adquisition Data for experiments';
            app.UIPrediciton = uiaxes(app.AdquisitionDataforexperimentsPanel);
            title(app.UIPrediciton, 'Prediction')
            xlabel(app.UIPrediciton, '# mode')
            ylabel(app.UIPrediciton, 'Weight [nm]')
            zlabel(app.UIPrediciton, 'Z')
            app.CheckBox = uicheckbox(app.AdquisitionDataforexperimentsPanel);
            app.DisplayPredictionvaluesCheckBox = uicheckbox(app.AdquisitionDataforexperimentsPanel);
            app.DisplayPredictionvaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayPredictionvaluesCheckBoxValueChanged, true);
            app.DisplayPredictionvaluesCheckBox.Text = 'Display  Prediction values';
            app.LoadphasemapsButton = uibutton(app.AdquisitionDataforexperimentsPanel, 'push');
            app.LoadphasemapsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadphasemapsButtonPushed, true);
            app.LoadphasemapsButton.Text = 'Load phasemaps';
            app.EditField_2 = uieditfield(app.AdquisitionDataforexperimentsPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.TypefileEditFieldLabel = uilabel(app.AdquisitionDataforexperimentsPanel);
            app.TypefileEditFieldLabel.Text = 'Type file';
            app.TypefileEditField = uieditfield(app.AdquisitionDataforexperimentsPanel, 'text');
            app.TypefileEditField.Editable = 'off';
            app.PupilSizepxEditFieldLabel = uilabel(app.AdquisitionDataforexperimentsPanel);
            app.PupilSizepxEditFieldLabel.Text = 'Pupil Size [px]';
            app.PupilSizepxEditField = uieditfield(app.AdquisitionDataforexperimentsPanel, 'text');
            app.PupilSizepxEditField.Editable = 'off';
            app.RUNButton_2 = uibutton(app.AdquisitionDataforexperimentsPanel, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Enable = 'off';
            app.RUNButton_2.Text = 'RUN';
            app.NumberofSamplesEditFieldLabel = uilabel(app.AdquisitionDataforexperimentsPanel);
            app.NumberofSamplesEditFieldLabel.Text = 'Number of Samples';
            app.NumberofSamplesEditField = uieditfield(app.AdquisitionDataforexperimentsPanel, 'text');
            app.NumberofSamplesEditField.Editable = 'off';
            app.PauseCheckBox = uicheckbox(app.AdquisitionDataforexperimentsPanel);
            app.PauseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PauseCheckBoxValueChanged, true);
            app.PauseCheckBox.Text = 'Pause';
            app.CorrectionCheckBox = uicheckbox(app.AdquisitionDataforexperimentsPanel);
            app.CorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectionCheckBoxValueChanged, true);
            app.CorrectionCheckBox.Text = 'Correction';
            app.SavedirButton_2 = uibutton(app.AdquisitionDataforexperimentsPanel, 'push');
            app.SavedirButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavedirButton_2Pushed, true);
            app.SavedirButton_2.Text = {'Save'; 'dir'};
            app.prgressLabel = uilabel(app.AdquisitionDataforexperimentsPanel);
            app.prgressLabel.Text = '';
            app.Open_savepath_2 = uibutton(app.AdquisitionDataforexperimentsPanel, 'push');
            app.Open_savepath_2.ButtonPushedFcn = createCallbackFcn(app, @Open_savepath_2ButtonPushed, true);
            app.Open_savepath_2.Text = {'Open'; 'folder'};
            app.AllinfolderCheckBox = uicheckbox(app.AdquisitionDataforexperimentsPanel);
            app.AllinfolderCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllinfolderCheckBoxValueChanged, true);
            app.AllinfolderCheckBox.Text = 'All in folder';
            app.InteractionMatrixloaddataPanel = uipanel(app.ExperimentTab);
            app.InteractionMatrixloaddataPanel.Title = 'Interaction Matrix load data';
            app.LoadiMatButton = uibutton(app.InteractionMatrixloaddataPanel, 'push');
            app.LoadiMatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadiMatButtonPushed, true);
            app.LoadiMatButton.Text = 'Load iMat ';
            app.ModulationPanel_Experiment_3 = uipanel(app.InteractionMatrixloaddataPanel);
            app.ModulationPanel_Experiment_3.Title = 'Modulation';
            app.DigitalmodulationDropDown_4Label = uilabel(app.ModulationPanel_Experiment_3);
            app.DigitalmodulationDropDown_4Label.Text = 'Digital modulation';
            app.DigitalmodulationDropDown_2 = uidropdown(app.ModulationPanel_Experiment_3);
            app.DigitalmodulationDropDown_2.Items = {'None', 'Modulate using SLM', 'Modulate DM'};
            app.DigitalmodulationDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DigitalmodulationDropDown_2ValueChanged, true);
            app.DigitalmodulationDropDown_2.Value = 'None';
            app.NumberofstepsEditField_5Label = uilabel(app.ModulationPanel_Experiment_3);
            app.NumberofstepsEditField_5Label.Text = 'Number of steps';
            app.NumberofstepsEditField_2 = uieditfield(app.ModulationPanel_Experiment_3, 'numeric');
            app.NumberofstepsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofstepsEditField_2ValueChanged, true);
            app.NumberofstepsEditField_2.Editable = 'off';
            app.AmplitudelambdaDEditField_4Label = uilabel(app.ModulationPanel_Experiment_3);
            app.AmplitudelambdaDEditField_4Label.Text = 'Amplitude $\lambda / D$';
            app.AmplitudelambdaDEditField_2 = uieditfield(app.ModulationPanel_Experiment_3, 'numeric');
            app.AmplitudelambdaDEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudelambdaDEditField_2ValueChanged, true);
            app.AmplitudelambdaDEditField_2.Editable = 'off';
            app.ReadyLampLabel = uilabel(app.InteractionMatrixloaddataPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.InteractionMatrixloaddataPanel);
            app.iMat_info = uieditfield(app.InteractionMatrixloaddataPanel, 'text');
            app.iMat_info.Editable = 'off';
            app.CloseLoopcommingsoonTab = uitab(app.TabGroup);
            app.CloseLoopcommingsoonTab.SizeChangedFcn = createCallbackFcn(app, @CloseLoopcommingsoonTabSizeChanged, true);
            app.CloseLoopcommingsoonTab.Title = 'Close Loop (comming soon)';
            app.LoadiMatPanel = uipanel(app.CloseLoopcommingsoonTab);
            app.LoadiMatPanel.Title = 'Load iMat';
            app.LoadiMatdirectoryButton_2 = uibutton(app.LoadiMatPanel, 'push');
            app.LoadiMatdirectoryButton_2.Text = {'Load iMat '; 'directory'};
            app.LoadMatButton_2 = uibutton(app.LoadiMatPanel, 'push');
            app.LoadMatButton_2.Enable = 'off';
            app.LoadMatButton_2.Text = {'Load '; 'Mat '};
            app.ModulationPanel_Experiment_2 = uipanel(app.LoadiMatPanel);
            app.ModulationPanel_Experiment_2.Title = 'Modulation';
            app.DigitalmodulationDropDown_3Label = uilabel(app.ModulationPanel_Experiment_2);
            app.DigitalmodulationDropDown_3Label.Text = 'Digital modulation';
            app.DigitalmodulationDropDown_3 = uidropdown(app.ModulationPanel_Experiment_2);
            app.DigitalmodulationDropDown_3.Items = {'None', 'Modulate using SLM'};
            app.DigitalmodulationDropDown_3.ValueChangedFcn = createCallbackFcn(app, @DigitalmodulationDropDown_3ValueChanged, true);
            app.DigitalmodulationDropDown_3.Value = 'None';
            app.NumberofstepsEditField_4Label = uilabel(app.ModulationPanel_Experiment_2);
            app.NumberofstepsEditField_4Label.Text = 'Number of steps';
            app.NumberofstepsEditField_4 = uieditfield(app.ModulationPanel_Experiment_2, 'numeric');
            app.NumberofstepsEditField_4.ValueChangedFcn = createCallbackFcn(app, @NumberofstepsEditField_4ValueChanged, true);
            app.NumberofstepsEditField_4.Editable = 'off';
            app.AmplitudelambdaDEditField_3Label = uilabel(app.ModulationPanel_Experiment_2);
            app.AmplitudelambdaDEditField_3Label.Text = 'Amplitude $\lambda / D$';
            app.AmplitudelambdaDEditField_3 = uieditfield(app.ModulationPanel_Experiment_2, 'numeric');
            app.AmplitudelambdaDEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudelambdaDEditField_3ValueChanged, true);
            app.AmplitudelambdaDEditField_3.Editable = 'off';
            app.InteractionMatrixDropDown_2Label = uilabel(app.LoadiMatPanel);
            app.InteractionMatrixDropDown_2Label.Text = {'Interaction '; 'Matrix'};
            app.InteractionMatrixDropDown_2 = uidropdown(app.LoadiMatPanel);
            app.InteractionMatrixDropDown_2.Items = {};
            app.InteractionMatrixDropDown_2.Enable = 'off';
            app.InteractionMatrixDropDown_2.Value = {};
            app.Panel = uipanel(app.CloseLoopcommingsoonTab);
            app.Panel.Title = 'Panel';
            app.UIAxes_CCD = uiaxes(app.Panel);
            title(app.UIAxes_CCD, 'Cientific Camera')
            xlabel(app.UIAxes_CCD, '')
            ylabel(app.UIAxes_CCD, '')
            zlabel(app.UIAxes_CCD, 'Z')
            app.UIAxes_CCD.XTickLabel = '';
            app.UIAxes_CCD.YTick = [];
            app.UIAxes_CCD.YTickLabel = '';
            app.UIAxes_Sethl = uiaxes(app.Panel);
            title(app.UIAxes_Sethl, 'PSF Profile')
            xlabel(app.UIAxes_Sethl, '')
            ylabel(app.UIAxes_Sethl, '')
            zlabel(app.UIAxes_Sethl, 'Z')
            app.UIAxes_Sethl.XTick = [-1 -0.5 0 0.5 1];
            app.UIAxes_Sethl.XTickLabel = {''; '-0.5'; '0'; '0.5'; ''};
            app.UIAxes_Sethl.YTick = [0 25 50 75 100];
            app.UIAxes_Sethl.YTickLabel = {'0'; '25'; '50'; '75'; '100'};
            app.DMcalibrationTab = uitab(app.TabGroup);
            app.DMcalibrationTab.SizeChangedFcn = createCallbackFcn(app, @DMcalibrationTabSizeChanged, true);
            app.DMcalibrationTab.Title = 'DM calibration';
            app.ConnectDMPanel = uipanel(app.DMcalibrationTab);
            app.ConnectDMPanel.Title = 'Connect DM';
            app.LampLabel = uilabel(app.ConnectDMPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.ConnectDMPanel);
            app.Switch_2 = uiswitch(app.ConnectDMPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.CCDcameraviewButton = uibutton(app.ConnectDMPanel, 'push');
            app.CCDcameraviewButton.ButtonPushedFcn = createCallbackFcn(app, @CCDcameraviewButtonPushed, true);
            app.CCDcameraviewButton.Text = 'CCD camera view';
            app.StopCCDcameraButton = uibutton(app.ConnectDMPanel, 'push');
            app.StopCCDcameraButton.Text = {'Stop CCD'; ' camera'};
            app.CropmanualButton = uibutton(app.ConnectDMPanel, 'push');
            app.CropmanualButton.Text = {'Crop'; 'manual'};
            app.ZernikemodesDMcalibrationPanel = uipanel(app.DMcalibrationTab);
            app.ZernikemodesDMcalibrationPanel.Title = 'Zernike modes DM calibration';
            app.Ast45EditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.Ast45EditFieldLabel.Text = 'Ast45';
            app.Ast45EditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.Ast45EditField.Limits = [-1 1];
            app.Ast45EditField.ValueChangedFcn = createCallbackFcn(app, @Ast45EditFieldValueChanged, true);
            app.DefocusEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.DefocusEditFieldLabel.Text = 'Defocus';
            app.DefocusEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.DefocusEditField.Limits = [-1 1];
            app.DefocusEditField.ValueChangedFcn = createCallbackFcn(app, @DefocusEditFieldValueChanged, true);
            app.Ast0EditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.Ast0EditFieldLabel.Text = 'Ast0';
            app.Ast0EditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.Ast0EditField.Limits = [-1 1];
            app.Ast0EditField.ValueChangedFcn = createCallbackFcn(app, @Ast0EditFieldValueChanged, true);
            app.TreYEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.TreYEditFieldLabel.Text = 'TreY';
            app.TreYEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.TreYEditField.Limits = [-1 1];
            app.TreYEditField.ValueChangedFcn = createCallbackFcn(app, @TreYEditFieldValueChanged, true);
            app.ComXEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.ComXEditFieldLabel.Text = 'Com X';
            app.ComXEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.ComXEditField.Limits = [-1 1];
            app.ComXEditField.ValueChangedFcn = createCallbackFcn(app, @ComXEditFieldValueChanged, true);
            app.ComYEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.ComYEditFieldLabel.Text = 'Com Y';
            app.ComYEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.ComYEditField.Limits = [-1 1];
            app.ComYEditField.ValueChangedFcn = createCallbackFcn(app, @ComYEditFieldValueChanged, true);
            app.TreXEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.TreXEditFieldLabel.Text = 'TreX';
            app.TreXEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.TreXEditField.Limits = [-1 1];
            app.TreXEditField.ValueChangedFcn = createCallbackFcn(app, @TreXEditFieldValueChanged, true);
            app.TetYEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.TetYEditFieldLabel.Text = 'TetY';
            app.TetYEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.TetYEditField.Limits = [-1 1];
            app.TetYEditField.ValueChangedFcn = createCallbackFcn(app, @TetYEditFieldValueChanged, true);
            app.SAstYEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.SAstYEditFieldLabel.Text = 'SAstY';
            app.SAstYEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.SAstYEditField.Limits = [-1 1];
            app.SAstYEditField.ValueChangedFcn = createCallbackFcn(app, @SAstYEditFieldValueChanged, true);
            app.SAb3EditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.SAb3EditFieldLabel.Text = 'SAb3';
            app.SAb3EditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.SAb3EditField.Limits = [-1 1];
            app.SAb3EditField.ValueChangedFcn = createCallbackFcn(app, @SAb3EditFieldValueChanged, true);
            app.SAstXEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.SAstXEditFieldLabel.Text = 'SAstX';
            app.SAstXEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.SAstXEditField.Limits = [-1 1];
            app.SAstXEditField.ValueChangedFcn = createCallbackFcn(app, @SAstXEditFieldValueChanged, true);
            app.TetXEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.TetXEditFieldLabel.Text = 'TetX';
            app.TetXEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.TetXEditField.Limits = [-1 1];
            app.TetXEditField.ValueChangedFcn = createCallbackFcn(app, @TetXEditFieldValueChanged, true);
            app.StartModulationTestButton = uibutton(app.ZernikemodesDMcalibrationPanel, 'push');
            app.StartModulationTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartModulationTestButtonPushed, true);
            app.StartModulationTestButton.Enable = 'off';
            app.StartModulationTestButton.Text = {'Start Modulation'; 'Test'};
            app.AmpEditFieldLabel = uilabel(app.ZernikemodesDMcalibrationPanel);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.AmpEditField = uieditfield(app.ZernikemodesDMcalibrationPanel, 'numeric');
            app.AmpEditField.Limits = [0 1];
            app.AmpEditField.ValueChangedFcn = createCallbackFcn(app, @AmpEditFieldValueChanged, true);
            app.StopModulationTestButton = uibutton(app.ZernikemodesDMcalibrationPanel, 'push');
            app.StopModulationTestButton.ButtonPushedFcn = createCallbackFcn(app, @StopModulationTestButtonPushed, true);
            app.StopModulationTestButton.Enable = 'off';
            app.StopModulationTestButton.Text = 'Stop Modulation Test';
            app.LockcalbirationCheckBox = uicheckbox(app.ZernikemodesDMcalibrationPanel);
            app.LockcalbirationCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockcalbirationCheckBoxValueChanged, true);
            app.LockcalbirationCheckBox.Text = 'Lock calbiration';
            app.ResetTIPTILTButton = uibutton(app.ZernikemodesDMcalibrationPanel, 'push');
            app.ResetTIPTILTButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTIPTILTButtonPushed, true);
            app.ResetTIPTILTButton.Enable = 'off';
            app.ResetTIPTILTButton.Text = 'Reset TIP/TILT';
            app.ResetDMButton = uibutton(app.ZernikemodesDMcalibrationPanel, 'push');
            app.ResetDMButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDMButtonPushed, true);
            app.ResetDMButton.Enable = 'off';
            app.ResetDMButton.Text = 'Reset DM';
            app.PreviewDMcalibrationPanel = uipanel(app.DMcalibrationTab);
            app.PreviewDMcalibrationPanel.Title = 'Preview DM calibration';
            app.UIAxes8 = uiaxes(app.PreviewDMcalibrationPanel);
            title(app.UIAxes8, '')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            zlabel(app.UIAxes8, '')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8.YTickLabel = '';
            app.IncludeMasksTab = uitab(app.TabGroup);
            app.IncludeMasksTab.SizeChangedFcn = createCallbackFcn(app, @IncludeMasksTabSizeChanged, true);
            app.IncludeMasksTab.Title = 'Include Masks';
            app.SLMfourierplaneDigitalWFSPanel = uipanel(app.IncludeMasksTab);
            app.SLMfourierplaneDigitalWFSPanel.Title = 'SLM fourier plane (Digital WFS)';
            app.WFS_filter = uiaxes(app.SLMfourierplaneDigitalWFSPanel);
            title(app.WFS_filter, 'Fourier filter')
            xlabel(app.WFS_filter, 'X')
            ylabel(app.WFS_filter, 'Y')
            zlabel(app.WFS_filter, 'Z')
            app.LoadlButton_2 = uibutton(app.SLMfourierplaneDigitalWFSPanel, 'push');
            app.LoadlButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadlButton_2Pushed, true);
            app.LoadlButton_2.Enable = 'off';
            app.LoadlButton_2.Text = 'Loadl';
            app.GenerateButton_2 = uibutton(app.SLMfourierplaneDigitalWFSPanel, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Enable = 'off';
            app.GenerateButton_2.Text = 'Generate';
            app.Image2 = uiimage(app.SLMfourierplaneDigitalWFSPanel);
            app.Image2.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\fft_mask.jpg';
            app.AddCheckBox_2 = uicheckbox(app.SLMfourierplaneDigitalWFSPanel);
            app.AddCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AddCheckBox_2ValueChanged, true);
            app.AddCheckBox_2.Text = 'Add';
            app.SLMpupilplaneTelescopePanel = uipanel(app.IncludeMasksTab);
            app.SLMpupilplaneTelescopePanel.Title = 'SLM pupil plane (Telescope)';
            app.Tel_imag = uiaxes(app.SLMpupilplaneTelescopePanel);
            title(app.Tel_imag, 'Pupil Telescope')
            xlabel(app.Tel_imag, 'X')
            ylabel(app.Tel_imag, 'Y')
            zlabel(app.Tel_imag, 'Z')
            app.LoadlButton = uibutton(app.SLMpupilplaneTelescopePanel, 'push');
            app.LoadlButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlButtonPushed, true);
            app.LoadlButton.Enable = 'off';
            app.LoadlButton.Text = 'Loadl';
            app.ResetButton = uibutton(app.SLMpupilplaneTelescopePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.Image2_2 = uiimage(app.SLMpupilplaneTelescopePanel);
            app.Image2_2.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\pupil_mask.jpg';
            app.AddCheckBox = uicheckbox(app.SLMpupilplaneTelescopePanel);
            app.AddCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddCheckBoxValueChanged, true);
            app.AddCheckBox.Text = 'Add';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\Logo1.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\Logo2.png';
            app.p1 = uiimage(app.UIFigure);
            app.p1.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\pul.png';
            app.p2 = uiimage(app.UIFigure);
            app.p2.Enable = 'off';
            app.p2.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\pul.png';
            app.p3 = uiimage(app.UIFigure);
            app.p3.Enable = 'off';
            app.p3.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\pul.png';
            app.p4 = uiimage(app.UIFigure);
            app.p4.Enable = 'off';
            app.p4.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\pul.png';
            app.p5 = uiimage(app.UIFigure);
            app.p5.Enable = 'off';
            app.p5.ImageSource = 'C:\Users\Charlotte\Desktop\GUI_PULPOS\figs\pul.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end